classdef qPar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        JSDataHTML  matlab.ui.control.HTML
    end

    
    properties (Access = private)
        numclicks % Description
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.JSDataHTML.Data = 0;
            app.numclicks = 0;
            app.JSDataHTML.DataChangedFcn = @(src,event) createqParObj(app, event)           
        end

        % Callback function: JSDataHTML, UIFigure
        function createqParObj(app, event)
          global formData;
            global formID;
            global formParameters;

            global pmts;
            
            global qp;
            global j;
            
            global h;
            global p;
            global u;
            global ps;
            global sl;
            global p_slave_id;
            
            global p_job_name;
            
            global p_job_id;
            global p_parent_tasks;
            global p_task_type;
            global p_function;
            global p_rhs;
            
            global p_job_num;

%             Getting the Objects and comparing the form name to proceed
%             with data handling
            formData = app.JSDataHTML.Data;
            formID = split(formData, ':');
            formParameters = formID(2);
            

%             qPar Object Creation Form Data Handling
            if char(formID(1)) == "CreateObject"
                
                pmts = split(formParameters, ',');
                h = char(pmts(1));
                p = str2num(char(pmts(2)));
                u = char(pmts(3));
                ps = char(pmts(4));
                sl = char(pmts(5));
                p_slave_id = str2num(char(pmts(6)));
                
%                 disp(pmts);

                qp = qpar(h,p,u,ps,sl,p_slave_id);
                
%             qPar Job Creation Form Data Handling               
            elseif char(formID(1)) == "CreateJob"
                
                pmts = split(formParameters, ',');
                p_job_name = char(pmts(1));
             
%                 disp(pmts);
                
                j = qp.createJob(p_job_name);
                
%             qPar Task Creation Form Data Handling
            elseif char(formID(1)) == "CreateTask"
                
                pmts = split(formParameters,';');
                p_job_id = j;
                p_parent_tasks = 0;
                p_task_type = char(pmts(1));
                p_function = char(pmts(2));

%                 Handling and Splitting the Function Parameters
                p_rhs = {}
                
                if isempty(char(pmts(3))) == 1
                    k = qp.createTask(p_job_id,p_parent_tasks,p_task_type,p_function,{});
                else
                    pmtsa = split(char(pmts(3)),",");
                
                    for i=1:length(pmtsa)
                        if isempty(str2num(char(pmtsa(i)))) == 1
                            p_rhs{i} = char(pmtsa(i));
                        else
                            p_rhs{i} = str2num(char(pmtsa(i)));
                        end
                    end

                    k = qp.createTask(p_job_id,p_parent_tasks,p_task_type,p_function,p_rhs)
                end

%             qPar Submit Job Form Data Handling
            elseif char(formID(1)) == "SubmitJob"
                
                p_job_num = j;
                
%                 disp(formParameters);
                
                qp.submit(p_job_num);
                disp(qp)
            end
        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @createqParObj, true);
            app.JSDataHTML = uihtml(app.UIFigure);
            app.JSDataHTML.HTMLSource = 'index.html';
            app.JSDataHTML.DataChangedFcn = createCallbackFcn(app, @createqParObj, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qPar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end